classdef aquaprimis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIGDepthTime         matlab.ui.control.UIAxes
        UIGTempTime          matlab.ui.control.UIAxes
        STARTSwitchLabel     matlab.ui.control.Label
        STARTSwitch          matlab.ui.control.ToggleSwitch
        LampSerial           matlab.ui.control.Lamp
        LampRead             matlab.ui.control.Lamp
        ReadfileSwitchLabel  matlab.ui.control.Label
        ReadfileSwitch       matlab.ui.control.ToggleSwitch
        PressureGaugeLabel   matlab.ui.control.Label
        PressureGauge        matlab.ui.control.SemicircularGauge
        UIAxes               matlab.ui.control.UIAxes
        CLabel               matlab.ui.control.Label
        LabelTemperature     matlab.ui.control.Label
        barLabel             matlab.ui.control.Label
        LabelPressure        matlab.ui.control.Label
        XLabel               matlab.ui.control.Label
        LabelDistance        matlab.ui.control.Label
        DegreeLabel          matlab.ui.control.Label
        LabelDegrees         matlab.ui.control.Label
        PortDropDownLabel    matlab.ui.control.Label
        PortDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        depth;          % Vector Depth [m]
        time;           % Vector Time [s]
        pressure;       % Vector Pressure [bar]
        temperature;    % Vector Temperature [ºC]
        distance;       % Vector Distance [cm]
        radians;        % Degrees TO Radians of Servo [rad]
        s;              % Serial Port
        h;              % Animated line for Depth-time graph
        gain = 147.857;         % Gain = 5 + 80kOhm/Rg, when Rg=560 ohm
        rate = 5;               % Rate = 5 samples/sec
        sensibility = 0.02; 	% [V]/[bar]
        temp_max = 30;          % Maximum temperature of ocean, sea, lake or river
        dist_max = 400;         % [cm]
        dist_min = 2;           % [cm]
        max_samples = 1000;
        radio=400;              % [cm]
        stop;                   % True if we need to stop the app, False otherwise
    end
    
    methods (Static)
        % Measure the size/length of the file
        function samples_file = maxSamplesFile()
            file = fopen("resultats.txt",'r');
            data = textscan(file,'%d %d %d %f',[Inf 4]);
            col = data{1}; % For example, but it can be another column
            samples_file = length(col);
            fclose(file);
        end
        
        %   This function calculates the temperature from temperature sensor
        function temp = calcTemp(sensorValue)
             temp = ((sensorValue*5)/1023)*100;   
                % For LM35DZ, it works from 0º to 100º
            % temp = ((sensorValue*5)/1023)*100 - 50; 
                % For TMP36, it works from -50º to 125º
            %  1ºC/10mV = 1ºC/0.01V  
            % Output voltage from temperature sensor is in millivolts
        end
        
        %   This function calculates the pressure from pressure sensor
        function pressure = calcPressure(sensevalue,gain,sensibility)
            pressure = (((sensevalue*5)/(1023*gain))/sensibility)-1;
            % Hydrostatic pressure = total pressure -  atmospheric pressure
        end
        
        %   This function calculates the depth.
        function depth = calcDepth(pressureInBar)
            depth = pressureInBar*(3/0.3);    % Ph*(3 meters / 0.3 bar)
            depth(depth<0)=0;                 % If depth is <0, then depth = 0
        end

    end
    
    methods (Access = public)
        
        % Initialize values
        function [t_screen,pressure_max, depth_max] = initialize(app)
            t_screen = 100/app.rate;    % 20 sec on screen
            pressure_max = app.calcPressure(1023,app.gain,app.sensibility);
            depth_max = app.calcDepth(pressure_max);
            
            app.time = zeros(1,app.max_samples);
            app.depth = zeros(1,app.max_samples);
            app.pressure = zeros(1,app.max_samples);
            app.temperature = zeros (1,app.max_samples);
            app.distance = zeros(1,app.max_samples);
            app.radians = zeros(1,app.max_samples);
        end
        
        % Reading and gathering values
        function gathering(app, sample, time, data)
            % Cartesians Axes
            app.pressure(sample) = app.calcPressure(data(1),app.gain,app.sensibility);
            app.temperature(sample) = app.calcTemp(data(2));
            app.depth(sample) = app.calcDepth(app.pressure(sample));
            app.time(sample) = time;
            % Gauges
            app.PressureGauge.Value = app.pressure(sample);
            % Scatter
            app.distance(sample) = data(4);
            app.radians(sample) = deg2rad(data(3));
            callingLabels(app,sample);
        end
        
        % Print values in labels
        function callingLabels(app,sample)
            app.LabelDegrees.Text = num2str(rad2deg(app.radians(sample)),3);
            app.LabelDistance.Text = num2str(app.distance(sample));
            app.LabelTemperature.Text = num2str(app.temperature(sample));
            app.LabelPressure.Text = num2str(app.pressure(sample));
        end
        
        % Drawing live
        function scatterAxes = drawing(app,animatedline,sample)
            addpoints(animatedline(1),app.time(sample),app.depth(sample));
            addpoints(animatedline(2),app.time(sample),app.temperature(sample));
            scatterAxes = scatter(app.UIAxes,app.distance(sample)*cos(app.radians(sample)),...
                    app.distance(sample)*sin(app.radians(sample)),200,'filled',"MarkerFaceColor","w");
            if (app.distance(sample) < app.dist_min) || (app.distance(sample) > app.dist_max) 
                scatterAxes.Visible = 'Off';
            end
            drawnow limitrate
        end
        
        % Modify X Axis
        function time = variable_time(app,sample,t_screen)
            time = (sample-1)/app.rate;
            if mod(sample-1,100) == 0
                app.UIGDepthTime.XLim = [time (time+t_screen)];
                app.UIGTempTime.XLim = app.UIGDepthTime.XLim;
            end
        end
        
        % read Serial, Fill file and Read file
        function mainFunction(app, IDswitch)
            global  dc
            n_samples = 1;
            
            [t_screen,~,depth_max]=initialize(app);
            
            hAnimatedLine(1) = animatedline(app.UIGDepthTime,'Color','#1f7ef2','LineWidth',3);
            hAnimatedLine(2) = animatedline(app.UIGTempTime,'Color','#1f7ef2','LineWidth',3);
            
            app.UIGDepthTime.YLim = [0 round(depth_max)];
            app.UIGTempTime.YLim = [0 round(app.temp_max)];
            
            switch IDswitch
                case 1
                    maxSamples = app.maxSamplesFile();
                    dc = fopen("resultats.txt",'r');
                    while (n_samples <= maxSamples) && (app.stop ~= true)
                        if strcmp(app.ReadfileSwitch.Value,'Off') || n_samples==maxSamples
                            app.stop=true;
                            app.ReadfileSwitch.Value = 'Off';
                            fclose(dc);
                            break;
                        end
                        t = variable_time(app,n_samples,t_screen);
                        A = fscanf(dc,'%d %d %d %f',[4 1]);
                        gathering(app,n_samples,t,A');
                        aux = drawing(app,hAnimatedLine,n_samples);
                        n_samples = n_samples + 1;
                        delete(aux);
                    end
                case 2
                    dc = fopen("resultats.txt",'w');
                    pause(1);
                    while (n_samples <= app.max_samples) && (app.stop ~= true)
                        if strcmp(app.STARTSwitch.Value,'Off') || n_samples==app.max_samples
                            app.stop=1;
                            app.STARTSwitch.Value = 'Off';
                            fclose(dc);
                            delete(app.s);
                            break;
                        end
                        t = variable_time(app,n_samples,t_screen);
                        do = true;
                        while do==true
                            A=readline(app.s); 
                            str=split(A);
                            if length(str) == 4
                                do = false;
                                data = str2double(str);
                            end
                        end
                        fprintf(dc,'%d\t%d\t%d\t%f\n',data);
                        gathering(app,n_samples,t,data);
                        aux = drawing(app,hAnimatedLine,n_samples);
                        n_samples = n_samples + 1;
                        delete(aux);
                    end
            end
        end
        
        % Draw a semicircle
        function drawSemiCircle(app)
            global gridRadioX gridRadioY gridThetaX gridThetaY 
            % Draw the arcs lines
            for R=100:100:app.radio
                k=1;
                for i=1:1:180
                    gridRadioX(k)=R*cos(i*pi/180);
                    gridRadioY(k)=R*sin(i*pi/180);
                    k=k+1;
                end
                plot(app.UIAxes,gridRadioX,gridRadioY,'color','#139fff','LineWidth',2);
                hold(app.UIAxes,"on");
            end
            % Draw the grid lines
            for theta=0:30:180
                for i=100:100:app.radio
                    gridThetaX(i)=i*cos(theta*pi/180);
                    gridThetaY(i)=i*sin(theta*pi/180);
                end
                plot(app.UIAxes,0,0,gridThetaX,gridThetaY,'color','#139fff','LineWidth',2);
                hold(app.UIAxes,"on");
            end
            labels = cellstr(num2str([0:30:180]'));
            text(app.UIAxes,[380;345;190;-10;-210;-355;-395], ...
                [10;200;350;400;350;200;10],labels,"Color",'white',"BackgroundColor",'b');
        end     
        
        % This function reset UIAxes and label text
        function cleaning(app)
            cla(app.UIGDepthTime);
            cla(app.UIGTempTime);
            cla(app.UIAxes);
            
            app.LabelDegrees.Text = " ";
            app.LabelDistance.Text = " ";
            app.LabelTemperature.Text = " ";
            app.LabelPressure.Text = " ";
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawSemiCircle(app);
            app.PortDropDown.Enable = false;
            app.PortDropDown.Items = " ";
            app.stop=false;
            delete(instrfind);
        end

        % Value changed function: ReadfileSwitch
        function ReadfileSwitchValueChanged(app, event)
            value = app.ReadfileSwitch.Value;
            if strcmp(value,'On')
                app.LampRead.Color = "green";
                startupFcn(app);
                mainFunction(app,1);
            elseif strcmp(value,'Off') || app.stop==true
                app.LampRead.Color = "red";
                cleaning(app);
            end    
        end

        % Value changed function: STARTSwitch
        function STARTSwitchValueChanged(app, event)
            value = app.STARTSwitch.Value;
            if strcmp(value,'On')
                app.LampSerial.Color = "green";
                app.PortDropDown.Enable = true;
                app.PortDropDown.Items=serialportlist;
                PortDropDownValueChanged(app,event);
                mainFunction(app,2);
            elseif strcmp(value,'Off') || app.stop==true
                app.LampSerial.Color = "red";
                app.PortDropDown.Enable = false;
                cleaning(app);
                delete(instrfind); instrfind;
                app.PortDropDown.Items=serialportlist;
            end
        
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            value = app.PortDropDown.Value;
            instrfind;
            app.s = serialport(value,9600);
            configureTerminator(app.s,"CR/LF");
            flush(app.s);
            startupFcn(app);
            main(app,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIGDepthTime = uiaxes(app.UIFigure);
            title(app.UIGDepthTime, 'Depth[m] and Time[s]')
            xlabel(app.UIGDepthTime, 'Time[s]')
            ylabel(app.UIGDepthTime, 'Depth[m]')
            app.UIGDepthTime.XLim = [0 20];
            app.UIGDepthTime.YLim = [0 7];
            app.UIGTempTime = uiaxes(app.UIFigure);
            title(app.UIGTempTime, 'Temperature [ºC] and Time [s]')
            xlabel(app.UIGTempTime, 'Time [s]')
            ylabel(app.UIGTempTime, 'Temperature [ºC]')
            app.UIGTempTime.XLim = [0 20];
            app.UIGTempTime.YLim = [0 30];
            app.STARTSwitchLabel = uilabel(app.UIFigure);
            app.STARTSwitchLabel.Text = 'START';
            app.STARTSwitch = uiswitch(app.UIFigure, 'toggle');
            app.STARTSwitch.ValueChangedFcn = createCallbackFcn(app, @STARTSwitchValueChanged, true);
            app.LampSerial = uilamp(app.UIFigure);
            app.LampRead = uilamp(app.UIFigure);
            app.ReadfileSwitchLabel = uilabel(app.UIFigure);
            app.ReadfileSwitchLabel.Text = 'Read file';
            app.ReadfileSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ReadfileSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadfileSwitchValueChanged, true);
            app.PressureGaugeLabel = uilabel(app.UIFigure);
            app.PressureGaugeLabel.Text = 'Pressure';
            app.PressureGauge = uigauge(app.UIFigure, 'semicircular');
            app.PressureGauge.Limits = [0 1];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Degrees')
            app.UIAxes.XLim = [-400 400];
            app.UIAxes.YLim = [0 400];
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = ' ºC';
            app.LabelTemperature = uilabel(app.UIFigure);
            app.LabelTemperature.Text = '';
            app.barLabel = uilabel(app.UIFigure);
            app.barLabel.Text = 'bar';
            app.LabelPressure = uilabel(app.UIFigure);
            app.LabelPressure.Text = '';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.LabelDistance = uilabel(app.UIFigure);
            app.LabelDistance.Text = '';
            app.DegreeLabel = uilabel(app.UIFigure);
            app.DegreeLabel.Text = 'Degree:';
            app.LabelDegrees = uilabel(app.UIFigure);
            app.LabelDegrees.Text = '';
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aquaprimis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end